classdef formulariob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        tabla                   matlab.ui.control.Table
        EliminarButton          matlab.ui.control.Button
        AnularButton            matlab.ui.control.Button
        ModificarButton         matlab.ui.control.Button
        GuardarButton           matlab.ui.control.Button
        txtY                    matlab.ui.control.NumericEditField
        VALORYEditFieldLabel    matlab.ui.control.Label
        txtX                    matlab.ui.control.NumericEditField
        VALORXEditFieldLabel    matlab.ui.control.Label
        FORMULARIODEDATOSLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        idDatoG % Description
    end
    
    methods (Access = private)
        
        function mostrar(app)
            app.tabla.Data=consultaDato
            
            app.txtY.Value=0
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mostrar(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            valorX=app.txtX.Value
            valorY=app.txtY.Value
            insertarDato(valorX,valorY,1)
            msgbox("Registro Insertado")
            mostrar(app)
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            datoX=app.txtX.Value
            datoY=app.txtY.Value
            actualizarDato(app.idDatoG,datoX,datoY,1)
            msgbox("Registro Modificado")
            mostrar(app)
        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
            selection = app.tabla.Selection;
            if ~isempty(selection)
            filaSeleccionada=selection(1);
            app.idDatoG=app.tabla.Data{filaSeleccionada,1}
            datoX=app.tabla.Data{filaSeleccionada,2}
            datoY=app.tabla.Data{filaSeleccionada,3}
            app.txtX.Value=datoX
            app.txtY.Value=datoY
            end
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)
            anularDato(app.idDatoG)
            msgbox("Registro Anulado")
            mostrar(app)
           
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            eliminarDato(app.idDatoG)
            msgbox("Registro Eliminado")
            mostrar(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FORMULARIODEDATOSLabel = uilabel(app.UIFigure);
            app.FORMULARIODEDATOSLabel.Text = 'FORMULARIO DE DATOS';
            app.VALORXEditFieldLabel = uilabel(app.UIFigure);
            app.VALORXEditFieldLabel.Text = 'VALOR X';
            app.txtX = uieditfield(app.UIFigure, 'numeric');
            app.VALORYEditFieldLabel = uilabel(app.UIFigure);
            app.VALORYEditFieldLabel.Text = 'VALOR Y';
            app.txtY = uieditfield(app.UIFigure, 'numeric');
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Valor X'; 'Valor Y'};
            app.tabla.RowName = {};
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = formulariob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end